classdef TravelTimeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EntersourcexcoordinatesEditFieldLabel  matlab.ui.control.Label
        EntersourcexcoordinatesEditField  matlab.ui.control.NumericEditField
        LoadmaterialmapDropDownLabel    matlab.ui.control.Label
        LoadmaterialmapDropDown         matlab.ui.control.DropDown
        PlotTravelTimeFieldButton       matlab.ui.control.Button
        EntersourceycoordinatesEditFieldLabel  matlab.ui.control.Label
        EntersourceycoordinatesEditField  matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        PlotMaterialMapButton           matlab.ui.control.Button
        TravelTimesCalculatorLabel      matlab.ui.control.Label
        TravelTimesatReceiversButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SaveTravelTimesasdatFileButton  matlab.ui.control.Button
        EnterreceiverxcoordinatesEditField_2Label  matlab.ui.control.Label
        EnterreceiverxcoordinatesEditField_2  matlab.ui.control.EditField
        EnterreceiverycoordinatesEditField_2Label  matlab.ui.control.Label
        EnterreceiverycoordinatesEditField_2  matlab.ui.control.EditField
        ClearallButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        matmap % material properties
        sw1
        rxConverted
        ryConverted
        ttf
        srtime
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotTravelTimeFieldButton
        function PlotTravelTimeFieldButtonPushed(app, event)

            m=app.matmap;
            valuex = app.EntersourcexcoordinatesEditField.Value;
            if valuex<=0 || valuex>size(m,1)
            f = uifigure;
            uialert(f,'Source (x) lies outside bounds','Input error');
            end
            
            valuey = app.EntersourceycoordinatesEditField.Value;
            if valuey<=0 || valuey>size(m,2)
            f = uifigure;
            uialert(f,'Source (y) lies outside bounds','Input error');
            end

            
            app.ttf=modrays(valuex-1,valuey-1,m);
            contour(app.UIAxes_3,app.ttf,20)
            hold(app.UIAxes_3);
            plot(app.UIAxes_3,valuex,valuey,'rx','LineWidth',2)
  
        end

        % Value changed function: LoadmaterialmapDropDown
        function LoadmaterialmapDropDownValueChanged(app, event)
            value = app.LoadmaterialmapDropDown.Value;
                if strcmp(value,'Homogeneous')
                  map=5900*ones(100,100);
                  err_sw=0;
                elseif strcmp(value,'Random')
                  map=randi([5500,6500],100);
                  err_sw=0;
                elseif strcmp(value,'Blocks')
                  map1=horzcat(5900*ones(50,50),4300*ones(50,50));
                  map2=horzcat(5000*ones(50,50),6800*ones(50,50));
                  map=vertcat(map1,map2);
                  err_sw=0;
                elseif strcmp(value,'Select one')
                  err_sw=1;
                  map=0;
                elseif strcmp(value,'Other')
                  Browsefilename = uigetfile('*.mat');
                  load(Browsefilename);
                  err_sw=0;
                end
             app.matmap=map;
             app.sw1=err_sw;
        end

        % Button pushed function: PlotMaterialMapButton
        function PlotMaterialMapButtonPushed(app, event)
             if app.sw1==1
                f = uifigure;
                uialert(f,'Invalid map selection made','Input error');
             end
             imagesc(app.UIAxes_2,app.matmap)
        end

        % Button pushed function: TravelTimesatReceiversButton
        function TravelTimesatReceiversButtonPushed(app, event)
            
            rx = app.EnterreceiverxcoordinatesEditField_2.Value;
            TFx = isstrprop(rx,'alpha');
            if sum(TFx)~=0
            f = uifigure;
            uialert(f,'Receiver (x) input not numeric','Input error');
            end
            
            ry = app.EnterreceiverycoordinatesEditField_2.Value;
            TFy = isstrprop(ry,'alpha');
            if sum(TFy)~=0
            f = uifigure;
            uialert(f,'Receiver (y) input not numeric','Input error');
            end
            
            m=app.matmap;         
            app.rxConverted=str2double(strsplit(rx,','));
            app.ryConverted=str2double(strsplit(ry,','));
            
            if min(app.rxConverted)<=0 || max(app.rxConverted)>size(m,2) || min(app.ryConverted)<=0 || max(app.ryConverted)>size(m,1)
            f = uifigure;
            uialert(f,'Receivers out of bounds','Input error');
            elseif length(app.rxConverted)~=length(app.ryConverted)
            f = uifigure;
            uialert(f,'X and y receiver coordinate vectors must be of equal length','Input error');
            else
            plot(app.UIAxes_3,app.rxConverted,app.ryConverted,'kx','LineWidth',2)
            end        
           
            
            for i=1:length(app.rxConverted)
            app.srtime(i)=app.ttf(app.ryConverted(i),app.rxConverted(i));
            end

            plot(app.UIAxes,app.srtime)
            app.UIAxes.YLim = [min(app.srtime) max(app.srtime)];
        end

        % Button pushed function: SaveTravelTimesasdatFileButton
        function SaveTravelTimesasdatFileButtonPushed(app, event)
            rx1=app.rxConverted;
            ry1=app.ryConverted;
            srt1=app.srtime;
            tdata=[rx1;ry1;srt1];
                fid = fopen('traveltimes.dat','w');
                fprintf(fid, '%4.6f %4.6f %4.6f\n',tdata);
                fclose(fid);
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
             app.UIAxes.cla
             app.UIAxes_2.cla
             app.UIAxes_3.cla
             app.EntersourcexcoordinatesEditField.Value=0;
             app.EntersourceycoordinatesEditField.Value=0;
             app.LoadmaterialmapDropDown.Value='Select one';
             app.EnterreceiverxcoordinatesEditField_2.Value='';
             app.EnterreceiverycoordinatesEditField_2.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EntersourcexcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EntersourcexcoordinatesEditFieldLabel.Text = 'Enter source x coordinates';
            app.EntersourcexcoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadmaterialmapDropDownLabel = uilabel(app.UIFigure);
            app.LoadmaterialmapDropDownLabel.Text = 'Load material map';
            app.LoadmaterialmapDropDown = uidropdown(app.UIFigure);
            app.LoadmaterialmapDropDown.Items = {'Select one', 'Homogeneous', 'Random', 'Blocks', 'Other'};
            app.LoadmaterialmapDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadmaterialmapDropDownValueChanged, true);
            app.LoadmaterialmapDropDown.Value = 'Select one';
            app.PlotTravelTimeFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotTravelTimeFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTravelTimeFieldButtonPushed, true);
            app.PlotTravelTimeFieldButton.Text = 'Plot Travel Time Field';
            app.EntersourceycoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EntersourceycoordinatesEditFieldLabel.Text = 'Enter source y coordinates';
            app.EntersourceycoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Material Map')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 100];
            app.UIAxes_2.YLim = [0 100];
            app.UIAxes_2.XTick = [0 20 40 60 80 100];
            app.UIAxes_2.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_2.YTick = [0 20 40 60 80 100];
            app.UIAxes_2.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Traveltime field')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XLim = [0 100];
            app.UIAxes_3.YLim = [0 100];
            app.UIAxes_3.XTick = [0 20 40 60 80 100];
            app.UIAxes_3.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_3.YTick = [0 20 40 60 80 100];
            app.UIAxes_3.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.PlotMaterialMapButton = uibutton(app.UIFigure, 'push');
            app.PlotMaterialMapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMaterialMapButtonPushed, true);
            app.PlotMaterialMapButton.Text = 'Plot Material Map';
            app.TravelTimesCalculatorLabel = uilabel(app.UIFigure);
            app.TravelTimesCalculatorLabel.Text = 'Travel Times Calculator';
            app.TravelTimesatReceiversButton = uibutton(app.UIFigure, 'push');
            app.TravelTimesatReceiversButton.ButtonPushedFcn = createCallbackFcn(app, @TravelTimesatReceiversButtonPushed, true);
            app.TravelTimesatReceiversButton.Text = 'Travel Times at Receivers';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Travel time to Receivers')
            xlabel(app.UIAxes, 'Receiver Index')
            ylabel(app.UIAxes, 'Travel Time')
            app.SaveTravelTimesasdatFileButton = uibutton(app.UIFigure, 'push');
            app.SaveTravelTimesasdatFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTravelTimesasdatFileButtonPushed, true);
            app.SaveTravelTimesasdatFileButton.Text = 'Save Travel Times as .dat File';
            app.EnterreceiverxcoordinatesEditField_2Label = uilabel(app.UIFigure);
            app.EnterreceiverxcoordinatesEditField_2Label.Text = 'Enter receiver x coordinates';
            app.EnterreceiverxcoordinatesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterreceiverycoordinatesEditField_2Label = uilabel(app.UIFigure);
            app.EnterreceiverycoordinatesEditField_2Label.Text = 'Enter receiver y coordinates';
            app.EnterreceiverycoordinatesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TravelTimeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end